classdef app33 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        UIAxes_2         matlab.ui.control.UIAxes
        UIAxes_3         matlab.ui.control.UIAxes
        UIAxes_4         matlab.ui.control.UIAxes
        UIAxes_5         matlab.ui.control.UIAxes
        UIAxes_7         matlab.ui.control.UIAxes
        UIAxes_8         matlab.ui.control.UIAxes
        UIAxes_6         matlab.ui.control.UIAxes
        UIAxes_9         matlab.ui.control.UIAxes
        ChooseButton     matlab.ui.control.Button
        Image            matlab.ui.control.Image
        Image2           matlab.ui.control.Image
        Image3           matlab.ui.control.Image
        Label            matlab.ui.control.Label
        Image5           matlab.ui.control.Image
        VehicleDetectionandTrackingsystemLabel  matlab.ui.control.Label
        SimulationLabel  matlab.ui.control.Label
        Image7           matlab.ui.control.Image
        Image6_2         matlab.ui.control.Image
        Image8           matlab.ui.control.Image
        Image9           matlab.ui.control.Image
        Image10          matlab.ui.control.Image
        Image11_2        matlab.ui.control.Image
        Image11_3        matlab.ui.control.Image
        Image11_4        matlab.ui.control.Image
        Image11_5        matlab.ui.control.Image
        Image11_6        matlab.ui.control.Image
        Image11_7        matlab.ui.control.Image
        Image11_8        matlab.ui.control.Image
        Image11_9        matlab.ui.control.Image
        Label_3          matlab.ui.control.Label
        Label_4          matlab.ui.control.Label
        Label_5          matlab.ui.control.Label
        Label_6          matlab.ui.control.Label
        Label_7          matlab.ui.control.Label
        Label_8          matlab.ui.control.Label
        Label_9          matlab.ui.control.Label
        Label_10         matlab.ui.control.Label
        Image12          matlab.ui.control.Image
        ClickonthisbuttontoimportanimageofyourchoiceLabel  matlab.ui.control.Label
        InsertButton     matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
        Image11_10       matlab.ui.control.Image
        Label_11         matlab.ui.control.Label
        Button           matlab.ui.control.StateButton
        Button_2         matlab.ui.control.StateButton
    end

    
    properties (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Callback function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
            
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)

%clc
%close all;
%clear;
load imgfildata;
%load matlab;

global picture;

%global picture;
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            picture=imread(filename);
            [~,cc]=size(picture);
            picture = imresize(picture,[300 500]);
           
            imshow(picture,'Parent',app.UIAxes);


if size(picture,3)==3
  picture=rgb2gray(picture);
  imshow(picture,'Parent',app.UIAxes_2);
end
% se=strel('rectangle',[5,5]);
% a=imerode(picture,se);
% figure,imshow(a);
% b=imdilate(a,se);
threshold = graythresh(picture);
picture =~im2bw(picture,threshold);
imshow(picture,'Parent',app.UIAxes_3);
picture = bwareaopen(picture,30);
imshow(picture,'Parent',app.UIAxes_4);
%imshow(picture)
if cc>2000
    picture1=bwareaopen(picture,3500);
else
picture1=bwareaopen(picture,3000);
end
%figure,
imshow(picture1,'Parent',app.UIAxes_5);
picture2=picture-picture1;
%figure,
imshow(picture2,'Parent',app.UIAxes_6);
picture2=bwareaopen(picture2,200);
%figure,
imshow(picture2,'Parent',app.UIAxes_7);
%------------
figure;
imshow(picture2);
[L,Ne]=bwlabel(picture2);
propied=regionprops(L,'BoundingBox');
hold on
pause(1)
for n=1:size(propied,1)
  rectangle('Position',propied(n).BoundingBox,'EdgeColor','g','LineWidth',2)
end
hold off
imshow(picture2,'Parent',app.UIAxes_8);


final_output=[];
t=[];
%------------
for n=1:Ne
  [r,c] = find(L==n);
  n1=picture(min(r):max(r),min(c):max(c));
  n1=imresize(n1,[42,24]);
  
  imshow(n1,'Parent',app.UIAxes_9);
  
  pause(0.2)
  x=[ ];

totalLetters=size(imgfile,2);

 for k=1:totalLetters
    
    y=corr2(imgfile{1,k},n1);
    x=[x y];
    
 end
 t=[t max(x)];
 if max(x)>.45
 z=find(x==max(x));
 out=cell2mat(imgfile(2,z));

final_output=[final_output out];
end
end
fid = fopen('number_Plate_excell.csv','a+');
%fprintf(fid,'PlateNumber\n');
%file = fopen('number_Plate.txt', 'a+');
%c = {'NumberPlate' ; [final_output]};
global c;
%data=[;];

data = readtable('number_Plate_excell.csv');
c = {[final_output]};
setappdata(0,'evalue',c);

%data=[;];
%data.A(:) = c;
%data2=[data ; c];
%file = fopen('number_Plate_excel.csv', 'a+');.
%writecell(data2, 'number_Plate_excell.csv','WriteMode','append');
%fprintf(fid,'%s\n',data2{1,:});
%fprintf(fid,'%f\n',c{2:end,:});
%fprintf(file,'%s\n',final_output);
%fclose(file);
%winopen('number_Plate_excell.csv')
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            app5();
            app.delete;
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            value = app.Button_2.Value;
            app22();
            app.delete;
        end

        % Button pushed function: InsertButton
        function InsertButtonPushed(app, event)
            app4();
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'step')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            zlabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'step')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            zlabel(app.UIAxes_3, '')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'step')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            zlabel(app.UIAxes_4, '')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'step')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, '')
            zlabel(app.UIAxes_5, '')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'step')
            xlabel(app.UIAxes_7, '')
            ylabel(app.UIAxes_7, '')
            zlabel(app.UIAxes_7, '')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'step')
            xlabel(app.UIAxes_8, '')
            ylabel(app.UIAxes_8, '')
            zlabel(app.UIAxes_8, '')
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'step')
            xlabel(app.UIAxes_6, '')
            ylabel(app.UIAxes_6, '')
            zlabel(app.UIAxes_6, '')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.UIAxes_9 = uiaxes(app.UIFigure);
            title(app.UIAxes_9, 'step')
            xlabel(app.UIAxes_9, '')
            ylabel(app.UIAxes_9, '')
            zlabel(app.UIAxes_9, '')
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.ChooseButton = uibutton(app.UIFigure, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'recv.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'c2.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'car.gif';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'The system is designed for '; 'recognizing of English'; ' vehicle license'; ' number plate.'};
            app.Image5 = uiimage(app.UIFigure);
            app.Image5.ImageSource = 'floral2180.jpg.png';
            app.VehicleDetectionandTrackingsystemLabel = uilabel(app.UIFigure);
            app.VehicleDetectionandTrackingsystemLabel.Text = {'Vehicle Detection'; ' and Tracking'; 'system'; ''};
            app.SimulationLabel = uilabel(app.UIFigure);
            app.SimulationLabel.Text = 'Simulation';
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = '2.png';
            app.Image6_2 = uiimage(app.UIFigure);
            app.Image6_2.ImageSource = '1.png';
            app.Image8 = uiimage(app.UIFigure);
            app.Image8.ImageSource = '33.png';
            app.Image9 = uiimage(app.UIFigure);
            app.Image9.ImageSource = '5.png';
            app.Image10 = uiimage(app.UIFigure);
            app.Image10.ImageSource = '8.png';
            app.Image11_2 = uiimage(app.UIFigure);
            app.Image11_2.ImageSource = '11.png';
            app.Image11_3 = uiimage(app.UIFigure);
            app.Image11_3.ImageSource = '11.png';
            app.Image11_4 = uiimage(app.UIFigure);
            app.Image11_4.ImageSource = '11.png';
            app.Image11_5 = uiimage(app.UIFigure);
            app.Image11_5.ImageSource = '11.png';
            app.Image11_6 = uiimage(app.UIFigure);
            app.Image11_6.ImageSource = '11.png';
            app.Image11_7 = uiimage(app.UIFigure);
            app.Image11_7.ImageSource = '11.png';
            app.Image11_8 = uiimage(app.UIFigure);
            app.Image11_8.ImageSource = '11.png';
            app.Image11_9 = uiimage(app.UIFigure);
            app.Image11_9.ImageSource = '11.png';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '4';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '7';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '2';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '5';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '8';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '3';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '6';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '9';
            app.Image12 = uiimage(app.UIFigure);
            app.Image12.ImageSource = '9.png';
            app.ClickonthisbuttontoimportanimageofyourchoiceLabel = uilabel(app.UIFigure);
            app.ClickonthisbuttontoimportanimageofyourchoiceLabel.Text = {'Click on this button '; 'to import an image '; 'of your choice , Or '; 'click on ''insert'' to '; 'insert informations '; 'of car''s owner'};
            app.InsertButton = uibutton(app.UIFigure, 'push');
            app.InsertButton.ButtonPushedFcn = createCallbackFcn(app, @InsertButtonPushed, true);
            app.InsertButton.Text = 'Insert';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'step')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Image11_10 = uiimage(app.UIFigure);
            app.Image11_10.ImageSource = '11.png';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '1';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Icon = 'ic.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Icon = 'rev2.png';
            app.Button_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app33

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end