classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image                     matlab.ui.control.Image
        Image2                    matlab.ui.control.Image
        Image3                    matlab.ui.control.Image
        Image4                    matlab.ui.control.Image
        Image7                    matlab.ui.control.Image
        Image8                    matlab.ui.control.Image
        Image5_2                  matlab.ui.control.Image
        Image6_2                  matlab.ui.control.Image
        Image5_3                  matlab.ui.control.Image
        NameEditFieldLabel        matlab.ui.control.Label
        NameEditField             matlab.ui.control.EditField
        IdentifierEditFieldLabel  matlab.ui.control.Label
        IdentifierEditField       matlab.ui.control.EditField
        CityEditFieldLabel        matlab.ui.control.Label
        CityEditField             matlab.ui.control.EditField
        PrenomEditFieldLabel      matlab.ui.control.Label
        PrenomEditField           matlab.ui.control.EditField
        Image9                    matlab.ui.control.Image
        Button                    matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app33();
            app.delete;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            a = app.NameEditField.Value;
            b = app.PrenomEditField.Value;
            cc = app.IdentifierEditField.Value;
            d = app.CityEditField.Value;
            data=readtable('number_Plate_excell.csv');

          c = getappdata(0,'evalue');
            data1={ a ,b ,cc ,d};
            %data=[;];
            data2=[c , data1];
            %data3 =[data ; data2];
            %data=[;];
            data3 =[data ; data2];

            writetable(data,'number_Plate_excell.csv');
            writetable(data3,'number_Plate_excell.csv');

            %writematrix(data,'number_Plate_excell.csv','WriteMode',"append"); 
            fid = fopen('number_Plate_excell.csv','a');
            %fid = fprintf(fid,'%s\n',m{:,1});
            winopen('number_Plate_excell.csv')
            
 
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
             value = app.NameEditField.Value;

        end

        % Value changing function: NameEditField
        function NameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'bc.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'pc4.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'gr1.png';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = '1.png';
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = 'Picture26.png';
            app.Image8 = uiimage(app.UIFigure);
            app.Image8.ImageSource = 'Picture27.png';
            app.Image5_2 = uiimage(app.UIFigure);
            app.Image5_2.ImageSource = 'Picture25.png';
            app.Image6_2 = uiimage(app.UIFigure);
            app.Image6_2.ImageSource = '33.png';
            app.Image5_3 = uiimage(app.UIFigure);
            app.Image5_3.ImageSource = 'Picture25.png';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.NameEditField.ValueChangingFcn = createCallbackFcn(app, @NameEditFieldValueChanging, true);
            app.IdentifierEditFieldLabel = uilabel(app.UIFigure);
            app.IdentifierEditFieldLabel.Text = 'Identifier';
            app.IdentifierEditField = uieditfield(app.UIFigure, 'text');
            app.CityEditFieldLabel = uilabel(app.UIFigure);
            app.CityEditFieldLabel.Text = 'City';
            app.CityEditField = uieditfield(app.UIFigure, 'text');
            app.PrenomEditFieldLabel = uilabel(app.UIFigure);
            app.PrenomEditFieldLabel.Text = 'Prenom';
            app.PrenomEditField = uieditfield(app.UIFigure, 'text');
            app.Image9 = uiimage(app.UIFigure);
            app.Image9.ImageSource = 'Picture28.png';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'rev2.png';
            app.Button.Text = '';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'post.png';
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end